classdef distributedHFBeamforming < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        loadingGIF                      matlab.ui.control.Image
        ElevationAngleEditField         matlab.ui.control.NumericEditField
        ElevationAngleSliderLabel       matlab.ui.control.Label
        ElevationAngleSlider            matlab.ui.control.Slider
        AzimuthAngleLabel               matlab.ui.control.Label
        AzimuthAngleSlider              matlab.ui.control.Slider
        AzimuthAngleEditField           matlab.ui.control.NumericEditField
        NotePositiveXisNorthLabel       matlab.ui.control.Label
        UserControlPanel                matlab.ui.container.Panel
        RunButton                       matlab.ui.control.Button
        MHzLabel                        matlab.ui.control.Label
        UITableTargets                  matlab.ui.control.Table
        TargetDirectionLabel            matlab.ui.control.Label
        UITableNulls                    matlab.ui.control.Table
        NullDirectionsLabel             matlab.ui.control.Label
        ButtonNullAdd                   matlab.ui.control.Button
        ButtonNullRemove                matlab.ui.control.Button
        ExportButton                    matlab.ui.control.Button
        NumberofAntennasEditFieldLabel  matlab.ui.control.Label
        NumberofAntennasEditField       matlab.ui.control.NumericEditField
        ArrayShapeDropDownLabel         matlab.ui.control.Label
        ArrayShapeDropDown              matlab.ui.control.DropDown
        FrequencyEditFieldLabel         matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        ArrayLocationDDLabel            matlab.ui.control.Label
        ArrayLocationEditFieldLat       matlab.ui.control.NumericEditField
        ArrayLocationEditFieldLon       matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        NotePositiveXisNorthLabel_2     matlab.ui.control.Label
        HFDistributedBeamformingModelLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_Map                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fc                          % frequency of signal
        lambda                      % wavelength of signal
        numAntennas                 % number of antennas
        antenna                     % currently selected antenna
        isULA                       % bool: True = ULA, False = UCA
        array                       % antenna array
        conformal_array             % conformal array used for simulation
        tiny_dipole                 % tiny dipole to use as undriven exciter in reflector
        inf_ground                  % reflector antenna used in conformal array as infinite ground plane
        az_null                     % null azimuth (degrees)
        az_target                   % target azimuth (degrees)
        ele_null                    % null elevation (degrees)
        ele_target                  % target elevation (degrees)
        UITableTargetsSelectedCells % for tracking cells to remove
        UITableNullsSelectedCells   % for tracking cells to remove
        map_fig                     % Hidden Figure object for mapping
        map_ax                      % Map Axes
        map_strokes                 % Anything that was added to the map (for deletion)
        array_lat                   % Latitude of Array
        array_lon                   % Longitude of Array
        slice_el_angle              % elevation angle used for slice plots
        slice_az_angle              % azimuth value used for slice plots
        spherical_plot_X            % X values of radiation pattern
        spherical_plot_Y            % Y values of radiation pattern
        spherical_plot_Z            % Z values of radiation pattern
        el_values                   % elevation values
        az_values                   % azimuth values
        pattern_values              % radiation pattern values
        altered_nulls               % wider null placement
        scatter_plots_el            % scatter plots used for elevation plots - DEPRECATED
        scatter_plots_az            % scatter plots used for azimuth plots - DEPRECATED
        derived_el_values           % elevation values derived from X, Y, Z values for scatter plot
        derived_az_values           % azimuth values derived from X, Y, Z values for scatter plot
        el_slice_plot               % scatter plot for elevation
        az_slice_plot               % scatter plot for azimuth
    end
    
    methods (Access = private)
                
        % Parses Input Parameters
        function [] = parseUserInput(app)       
            % Parse User Input
            app.numAntennas = app.NumberofAntennasEditField.Value;
            app.fc = app.FrequencyEditField.Value * 1e6;
            app.lambda = physconst('LightSpeed')/app.fc;
            app.az_null = app.UITableNulls.Data(:,1).';      % null azimuth (degrees)
            app.az_target = app.UITableTargets.Data(:,1).';   % target azimuth (degrees)
            app.ele_null = app.UITableNulls.Data(:,2).';    % null elevation (degrees)
            app.ele_target = app.UITableTargets.Data(:,2).';  % target elevation (degrees)
            app.array_lat = app.ArrayLocationEditFieldLat.Value;
            app.array_lon = app.ArrayLocationEditFieldLon.Value;
            app.isULA = strcmp(app.ArrayShapeDropDown.Value, 'ULA');
            if app.isULA
                app.array = phased.ULA('NumElements',app.numAntennas, ...
                                       'Element', app.antenna, ...
                                       'ElementSpacing', app.lambda/2);
            else
                app.array = phased.UCA('NumElements',app.numAntennas, ...
                                       'Element', app.antenna, ...
                                       'Radius', (app.lambda/4)/sind(360/(2*app.numAntennas))); % use trig to space out UCA elements each by lambda/2
            end
            
            app.tiny_dipole = dipole('Length',1e-3,'Width',1e-5); % reflector must have an antenna, so make it tiny so it has no impact
            app.inf_ground = reflector('Exciter', app.tiny_dipole, 'GroundPlaneLength',inf,'Spacing',0.002); % use a reflector as an infinite ground plane  
            
            for i = 1:app.numAntennas
               element{i} = app.antenna; % create list of elements for the conformal array
            end
            
            element{end+1} = app.inf_ground; % tack on the infinite ground
            elementpos = -(getElementPosition(app.array))'; % create list of antenna locations for the conformal array
            elementpos(:,3) = .1; % set the antennas 0.1m off the xy plane
            elementpos(end+1,:) = [0 0 0.001]; % for some reason the reflector can't be on the xy plane
            
            app.conformal_array = conformalArray('Element',element,'ElementPosition',elementpos); % construct the conformal array
            
        end
        
        % Performs ULA Beamforming
        function [data, az, el, wideEnemyNulls] = ulaBeamforming(app)
            % Multiple Wide Null Adaptive Beamformer
            %% Inputs
            N = app.numAntennas;    % Number of Elements
            azAlly = app.az_target; % Target Azimuth in degrees
            elAlly = app.ele_target; % Target Elevation in degrees
            azEnemy = app.az_null;  % Null Azimuths in degrees
            elEnemy = app.ele_null;  % Null Elevations in degrees
            
            azDiff = azAlly - azEnemy;
            for i=1:length(azDiff)
                if azDiff(i) > 90
                    azDiff(i) = 180 - azDiff(i);
                end
            end
            nullSpacing = 1.033.^(abs(azDiff))*1.5;      % Null spacing in degrees
            for i=1:length(nullSpacing)
                if nullSpacing(i) >= 5
                  nullSpacing(i) = 5;
                end
            end      
            
            %% Constants
            c = physconst('LightSpeed'); % signal propagation speed
            reservedDOF = [0 1 1 1 2 2 2]; % Reserved Degrees of Freedom based on N [2 3 4 ... 8]
        
            %% Null Widening/Placement
            % Determine number of available nulls to place, leaving some free
            availableNulls = N - 1 - reservedDOF(N-1);
            requiredNulls = length(azEnemy);
            if availableNulls < requiredNulls
                error("Error: With %i antennas, only %i nulls available. Requested %i nulls", N, availableNulls, requiredNulls);
            end
            
            % Determine how many nulls to assign to each requested enemy azimuth
            nullsPerEnemyAzimuth = availableNulls / requiredNulls;
            
            % Place all enemy nulls
            wideEnemyNulls = placeNulls(app, azDiff, azEnemy, elEnemy, nullsPerEnemyAzimuth, nullSpacing);
            
            %% Antenna Weight Calculations
            % Steering Matrix
            steeringMatrix = steervec(getElementPosition(app.array)/app.lambda, ...
                                      [[azAlly; elAlly] wideEnemyNulls]);
            
            % Desired Response
            desiredResponse = [1 zeros(1, size(wideEnemyNulls, 2))];
            
            % If we have a non-singular square matrix (N-1 nulls)
            % Honestly, we do not want to do this because we waste all of our
            % degrees of freedom placing nulls, leading to a performance loss
            % in the main beam
            if ~diff(size(steeringMatrix))
                antennaWeights = (desiredResponse*inv(steeringMatrix))'; % Array Weights
                
            % If singular matrix (less than N-1 nulls)
            % so that a solution can still be found.
            % We should be doing it this way, where we leave a few unplaced nulls to
            % allow for extra unused degrees of freedom so that the main lobe doesn't
            % suffer any performance degredation (apart from widening due to scanning)
            else
                antennaWeights = (desiredResponse*pinv(steeringMatrix))'; % Array Weights
            end
            
            magw = abs(antennaWeights); % conformal array takes mag and phase, not complex
            phasew = rad2deg(angle(antennaWeights));
            magw(end + 1) = 0; % ensure the dipole on the reflector is undriven
            phasew(end + 1) = 0; % ensure the dipole on the reflector is undriven
            
            app.conformal_array.AmplitudeTaper = magw;
            app.conformal_array.PhaseShift = phasew;
            
            [data, az, el] = pattern(app.conformal_array, app.fc, -180:2:180,0:2:90, 'Normalize', true);
            
        end
        
        % Performs UCA Beamforming       
        function [data, az, el, wideEnemyNulls] = ucaBeamforming(app)
            % Multiple Wide Null Adaptive Beamformer
            %% Inputs
            N = app.numAntennas;     % Number of Elements
            azAlly = app.az_target;  % Target Azimuth in degrees
            elAlly = app.ele_target; % Target Elevation in degrees
            azEnemy = app.az_null;   % Null Azimuths in degrees
            elEnemy = app.ele_null;  % Null Elevations in degrees
            nullSpacing = 5.*ones(length(azEnemy),1); % Null spacing in degrees
            azDiff = azAlly - azEnemy;

            %% Constants
            c = physconst('LightSpeed'); % signal propagation speed
            reservedDOF = [0 1 1 1 2 3 3]; % Reserved Degrees of Freedom based on N [2 3 4 ... 8]

            %% Null Widening/Placement
            % Determine number of available nulls to place, leaving some free
            availableNulls = N - 1 - reservedDOF(N-1);
            requiredNulls = length(azEnemy);
            if availableNulls < requiredNulls
                error("Error: With %i antennas, only %i nulls available. Requested %i nulls", N, availableNulls, requiredNulls);
            end
            
            % Determine how many nulls to assign to each requested enemy azimuth
            nullsPerEnemyAzimuth = availableNulls / requiredNulls;
            
            % Place all enemy nulls
            wideEnemyNulls = placeNulls(app, azDiff, azEnemy, elEnemy, nullsPerEnemyAzimuth, nullSpacing);
    
            %% Antenna Weight Calculations
            % Combine the lists of beam azimuths
            beams = [wideEnemyNulls(1,:) azAlly];
    
            % Determine the desired beamforming response
            [~,l] = min(abs(beams-azAlly));
            B = zeros(length(beams), 1);
            B(l)=1/sqrt(2);
    
            % Retrieve Steering Vectors
            d = steervec(getElementPosition(app.array)/app.lambda, [azAlly; elAlly]);
            V = steervec(getElementPosition(app.array)/app.lambda, wideEnemyNulls);
            G = [V d]';
    
            % LS Minimization
            cvx_begin quiet
                % The variables we want to solve for are the N complex antenna weights
                variable w(N) complex
    
                % Minimize the square of the L-2 Norm:
                % (||G*w - B||2)^2  (L-2 Norm = ||.||2)
                minimize((G*w-B)'*(G*w-B))
    
                % And subject the minimization to the following constraints
                subject to
                    % Gain of 1 in the desired direction
                    w'*d == 1
                    % Honestly not really sure what this one does... lol
                    %w'*w <= 1
                    % Gain of 0 in the null directions
                    w'*V == zeros(1, size(wideEnemyNulls, 2))
            cvx_end

            magw = abs(w); % conformal array takes mag and phase, not complex
            phasew = rad2deg(angle(w));
            magw(end + 1) = 0; % ensure the dipole on the reflector is undriven
            phasew(end + 1) = 0; % ensure the dipole on the reflector is undriven

            app.conformal_array.AmplitudeTaper = magw;
            app.conformal_array.PhaseShift = phasew;
            
            [data, az, el] = pattern(app.conformal_array, app.fc, -180:2:180,0:2:90, 'Normalize', true);
            
        
        end
        
        %
        % Function: divIntoInts
        % Purpose: breaks up numerator/divisor into groups of integers as
        %   close to each other as possible.
        %   Ex: 4/2 -> [2,2], 5/3 -> [2,2,1] 
        % Requires:
        %   - numerator and divisor must be an integer type
        function ints = divIntoInts(app, numerator, divisor)
            ints = zeros(1,divisor);
            ints(:) = idivide(int16(numerator),int16(divisor));
            
            leftover = mod(numerator,divisor);
            
            ints(1:leftover) = ints(1:leftover) + 1;
        end
        
        %
        % Function: genNullPattern
        % Purpose: given a target direction (az,el), and the number of
        %   nulls, it will automatically distribute the nulls in a circle
        %   with radius 'degree_radius'. Additionally, when the edge of the
        %   circle falls below a certain elevation (hor_buf),
        %   it will automatically redistribute itself into a semicircle where
        %   all of the nulls are still above the horizon buffer in elevation.
        function az_el = genNullPattern(app, target_az, target_el, degree_radius, num_nulls, hor_buf)
            % only one null so place it at the target
            if(num_nulls == 1)
                az_el = [target_az target_el];
            elseif (num_nulls > 1)
                if(target_el - degree_radius - hor_buf >= 0)
                    theta = linspace(0,360,num_nulls+1);
                    theta = theta(1:length(theta)-1);
                    delta_degr = 0;
                    az_el = [degree_radius*cosd(theta'+delta_degr)+target_az degree_radius*sind(theta'+delta_degr)+target_el];
                else
                    % circle dips below horizon.
                    % need to find the angle that intersects with horizon and
                    % arrange nulls to be in semicircle above the horizon
                    intersect_theta_max = asind(-(target_el - hor_buf)/degree_radius);
                    intersect_theta_max = intersect_theta_max + 360
                    intersect_theta_min = intersect_theta_max - 2*(intersect_theta_max - 270) + 360
    
                    theta = linspace(intersect_theta_min,intersect_theta_max,num_nulls);
                    theta = theta;
                    az_el = [degree_radius*cosd(theta')+target_az degree_radius*sind(theta')+target_el];
                end
            else
                % error
                disp("Error: fewer than one null");
            end
            az_el = az_el';
        end
        
        function r = roundToNearestElement(app, x, y)
            % rounds every element of x to nearest element in y
            % if there are two nearest elements, it rounds to the higher one
            maxy = max(y);
            miny = min(y);
            % values that are outside the min and max of y would be NaN otherwise
            x(x > maxy) = maxy;
            x(x < miny) = miny;
            r = interp1(y, y, x, 'nearest');
        end
        
        % set up the azimuth and elevation values for the lines on the 3D
        % radiation plot. The sliders under the constant elevation and 
        % constant azimuth plots select where the azimuth and elevation
        % slice lines go
        function [] = setUp3DLinePlots(app)
            
            app.derived_el_values = roundToNearestElement(app, atan2d(app.spherical_plot_Z,(sqrt(app.spherical_plot_X.^2 + app.spherical_plot_Y .^2))), app.el_values);
            % round every value to the nearest value
            app.derived_az_values = roundToNearestElement(app, atan2d(app.spherical_plot_Y, app.spherical_plot_X), app.az_values);
            app.derived_el_values(app.derived_el_values == -180) = 180;
            
            elvalues = app.derived_el_values;
            azvalues = app.derived_az_values;

            el_angle = 0;
            app.ElevationAngleSlider.Value = el_angle;
            app.ElevationAngleEditField.Value = el_angle;
            elindexes = elvalues == el_angle;
            
            az_angle = 0;
            app.AzimuthAngleSlider.Value = az_angle;
            app.AzimuthAngleEditField.Value = az_angle;
            azindexes = azvalues == az_angle;
            
            app.el_slice_plot = plot3(app.UIAxes_3, ...
                                            app.spherical_plot_X(elindexes), ...
                                            app.spherical_plot_Y(elindexes), ...
                                            app.spherical_plot_Z(elindexes), ...
                                            'black','visible', 'on');
            
            app.az_slice_plot = plot3(app.UIAxes_3, ...
                                            app.spherical_plot_X(azindexes), ...
                                            app.spherical_plot_Y(azindexes), ...
                                            app.spherical_plot_Z(azindexes), ...
                                            'black','visible', 'on');
            
            
        end


        % Uses extra degrees of freedom to place extra nulls around the
        % user selected nulls to improve null performance
        function wideEnemyNulls = placeNulls(app, azDiff, azEnemy, elEnemy, nullsPerEnemyAzimuth, nullSpacing)
            % Default
            wideEnemyNulls = [];
            
            % separates the extra degrees of freedom into the nulls. i.e if
            % there are 3 user input nulls and we have 5 degrees of
            % freedom, numNulls is [2 2 1]
            numNulls = divIntoInts(app, int16(nullsPerEnemyAzimuth * length(azEnemy)), length(azEnemy));
            
            % loop through and add all the nulls to the output array
            for i = 1 : length(azEnemy)
                wideEnemyNulls = [wideEnemyNulls genNullPattern(app, azEnemy(i), elEnemy(i), mean(nullSpacing), numNulls(i), 2)];
            end
            
            % ensure nulls are -180 to 180
            wideEnemyNulls = wrapTo180(wideEnemyNulls);
        end
        
        % Updates the World Map
        function [] = updateMap(app)
            % Wipe old lines
            set(0, 'CurrentFigure', app.map_fig);
            delete(app.map_strokes);
            app.map_strokes = [];
            clma all
            
            app.map_ax = axesm('MapProjection','mercator','MapLatLimit',[-75 80], ...
                                   'MapLonLimit', [app.array_lon-180 app.array_lon+180], 'Grid','off','Frame', 'on');
            setm(app.map_ax, 'meridianlabel', 'off', 'parallellabel', 'off')
            land = shaperead('landareas', 'UseGeoCoords', true);
            geoshow(app.map_ax, land, 'FaceColor', [0.5 0.7 0.5])
            tightmap;
            
            % Plot new map
            app.map_strokes(end+1) = plotm(app.array_lat, app.array_lon, 'bo');
            for i=1:length(app.az_target)
               [target_lat, target_lon] = track1('rh',app.array_lat,app.array_lon,app.az_target(i), min(50, abs(70-abs(app.array_lat))));
               app.map_strokes(end+1) = plotm(target_lat(end), target_lon(end), 'go');
               app.map_strokes(end+1) = linem([app.array_lat; target_lat(end)], [app.array_lon; target_lon(end)], 'g-');
            end
            for i=1:length(app.az_null)
               [null_lat, null_lon] = track1('rh',app.array_lat,app.array_lon,app.az_null(i), min(50, abs(70-abs(app.array_lat))));
               app.map_strokes(end+1) = plotm(null_lat(end), null_lon(end), 'ro');
               app.map_strokes(end+1) = linem([app.array_lat; null_lat(end)], [app.array_lon; null_lon(end)], 'r-');
            end
            cla(app.UIAxes_Map)
            copyobj(app.map_ax.Children, app.UIAxes_Map);  % Copy all of the axis' children to your app axis
        end
        
        % Clears All Plots
        function [] = clearOldPlots(app)
            cla(app.UIAxes)
            cla(app.UIAxes_2)
            cla(app.UIAxes_3)
            cla(app.UIAxes_4)
        end

        % due to matlab's multiple definitions of the term 'azimuth', we do
        % some internal correction to make sure it all aligns
        function [] = correctInputAzimuths(app)
            app.az_target = app.az_target * -1;
            app.az_null = app.az_null * -1;
        end
        
        % same as above        
        function [] = correctAzimuth(app)
            app.az_values = app.az_values * -1;
            app.az_target = app.az_target * -1;
            app.az_null = app.az_null * -1;
            app.altered_nulls = app.altered_nulls * -1;
        end
        
        function [] = correctTargetParameters(app)
             % Steering Matrix has bound restrictions of [-180, 180] for azimuth and [-90, 90] for elevation
             % This is to circumvent that restriction
            for al=1:length(app.az_target)
                if app.az_target(al) >= 360 || app.az_target(al) <= -360
                    app.az_target(al) = mod(app.az_target(al), 360);
                end
                if app.az_target(al) > 180
                    app.az_target(al) = -180 + mod(app.az_target(al), 180);
                elseif app.az_target(al) < -180
                    app.az_target(al) = mod(app.az_target(al), 180);
                end
                 % This is the same angle, but it goes from 180 to 0
            end
            for el=1:length(app.ele_target)
                if app.ele_target(el) > 90
                    app.ele_target(el) = 90 - mod(app.ele_target(el), 90);
                    % 90 is straight up and angles after that start coming
                    % back down
                elseif app.ele_target(el) < -90
                    app.ele_target(el) = 0 - mod(app.ele_target(el), 90);
                end
            end
        end
        
        function [] = correctNullParameters(app)
            % Steering Matrix has bound restrictions of [-180, 180] for azimuth and [-90, 90] for elevation
             % This is to circumvent that restriction
            for al=1:length(app.az_null)
                if app.az_null(al) >= 360 || app.az_null(al) <= -360
                    app.az_null(al) = mod(app.az_null(al), 360);
                end
                if app.az_null(al) > 180
                    app.az_null(al) = -180 + mod(app.az_null(al), 180);
                elseif app.az_null(al) < -180
                    app.az_null(al) = mod(app.az_null(al), 180);
                end
                 % This is the same angle, but it goes from 180 to 0
            end
            for el=1:length(app.ele_null)
                if app.ele_null(el) > 90
                    app.ele_null(el) = 90 - mod(app.ele_null(el), 90);
                    % 90 is straight up and angles after that start coming
                    % back down
                elseif app.ele_null(el) < -90
                    app.ele_null(el) = 0 - mod(app.ele_null(el), 90);
                end
            end
        end
        
        function keepGoing = nullWarning(app)
            %% Null Placement Warning
            azAlly = app.az_target; % Target Azimuth in degrees
            azEnemy = app.az_null;  % Null Azimuths in degrees
            nullDistance = 180; %furthest apart two angles can be
            keepGoing = true; %allows escape if don't want to load
            equalAz = false; % Remembers if the target and null azimuths are equal ans suppresses warning box
            for nd=1:length(azEnemy)
               for td=1:length(azAlly)
                   currentTarget = azAlly(td);
                   currentNull = azEnemy(nd);
                   currentDistance = abs(currentTarget - currentNull);
                   
                   if currentTarget == currentNull % Target Azimuth = Null Azimuth doesn't work and should only every be a typo
                       equalAz = true;
                       keepGoing = false;
                       errordlg('Target and Null cannot have the same azimuth.', 'Azimuth Input Error');
                   end
                   
                   if app.isULA %accounts for ULA mirroring across 90 and -90
                        if currentTarget >= 90
                            if currentNull <= 90 && currentNull >= 0
                                reflTarget = currentTarget - 90; %placeholder values, always positive
                                reflNull = 90 - currentNull;
                                currentDistance = abs(reflTarget - reflNull);
                            end
                        elseif currentTarget < 90 && currentTarget >= 0
                            if currentNull >= 90
                                reflTarget = 90 - currentTarget; %placeholder values,always positive
                                reflNull = currentNull - 90;
                                currentDistance = abs(reflTarget - reflNull);
                            end
                        elseif currentTarget > -90 && currentTarget <= 0
                            if currentNull <= -90
                                reflTarget = currentTarget + 90; %placeholder values, always positive
                                reflNull = 90 - currentNull;
                                currentDistance = abs(reflTarget - reflNull);
                            end
                        elseif currentTarget <= -90
                            if currentNull >= -90 && currentNull <= 0
                                reflTarget = currentTarget - 90; %placeholder values
                                reflNull = currentNull + 90; 
                                currentDistance = abs(reflTarget - reflNull);
                            end
                        end
                   end
                   
                   if(currentDistance>180)
                       currentDistance = 360 - currentDistance;
                   end
                   if(currentDistance<nullDistance)
                       nullDistance = currentDistance;
                   end
               end
            end
            if nullDistance<40 && ~equalAz 
                keepGoing = false;
                answer = questdlg('Null placement may lead to reduced power in target direction. Recommend placing nulls at least 40 degrees from target. If using ULA, nulls must also be placed at least 40 degrees from where target is reflected across +/- 90 azimuth. Continue anyway?','Warning: Close Target and Null Spacing');
                if(isequal(answer, "Yes"))
                    keepGoing = true;
                end
            end
        end

        
        function [] = handlePlotting(app)
            % Azimuth Plot
            plotAzimuth(app, 0);
            
            % Elevation Plot
            plotElevation(app, 0);
            
            % Plot 3D pattern
            plotRadiationPattern(app);
                   
            % Plot antenna array position
            plotAntennaArray(app);

            % Calculate/Update derived azimuth and elevation values and
            % set up scatter plots
            % Must be called before plotting slices
            setUp3DLinePlots(app);

            % Plot Azimuth Slice
            plotAzimuthSlice(app);
            
            % Plot Elevation Slice
            plotElevationSlice(app);
        end
        
        % Generates the Azimuth Plot (top left)
        % Also known as the Elevation Cross-Section, as it shows all
        % azimuth values at a fixed elevation
        function [] = plotAzimuth(app, desired_ele)
            cla(app.UIAxes)

            % Plot 2D azimuth cut
            diff = abs(app.el_values - desired_ele);
            index = find(diff == min(diff));
            plot(app.UIAxes, app.az_values, app.pattern_values(index,:))
            title_string = sprintf('Elevation Cross-Section (Elevation = %.1f', app.el_values(index));
            title_string = strcat(title_string , '\circ)');
            title(app.UIAxes, title_string);
            ylabel(app.UIAxes, 'Normalized Power (dB)')
            xlabel(app.UIAxes, 'Azimuth Angle (degrees)')
            hold(app.UIAxes, 'on')
            for i = 1:size(app.altered_nulls, 2)
                plot(app.UIAxes, [app.altered_nulls(1,i) app.altered_nulls(1,i)],[-1000 100],'r--','LineWidth',1)
            end
            for z = 1:length(app.az_target)
                plot(app.UIAxes, [app.az_target(z) app.az_target(z)],[-1000 100],'g--','LineWidth',1)
            end
            xlim(app.UIAxes, [-180 180]);
            ylim(app.UIAxes, [-100 10]);
            grid(app.UIAxes, 'on')
            hold(app.UIAxes, 'off')
        end
        
        % Generates the Elevation Plot (top right)
        % Also known as the Azimuth Cross-Section, as it shows all
        % elevation values at a fixed azimuth
        function [] = plotElevation(app, desired_az)
            cla(app.UIAxes_4)

            %% Plot elevation cut 2d
            diff = abs(app.az_values - desired_az);
            index = find(diff == min(diff));
            plot(app.UIAxes_4, app.el_values, app.pattern_values(:,index))
            title_string = sprintf('Azimuth Cross-Section (Azimuth = %.1f', app.az_values(index));
            title_string = strcat(title_string , '\circ)');
            title(app.UIAxes_4, title_string);
            ylabel(app.UIAxes_4, 'Normalized Power (dB)')
            xlabel(app.UIAxes_4, 'Elevation Angle (degrees)')
            xlim(app.UIAxes_4, [0 90]);
            ylim(app.UIAxes_4, [-100 10]);
            grid(app.UIAxes_4, 'on')
            hold(app.UIAxes_4, 'off')
        end
        
        % Plots the 3D Antenna Array (bottom right)
        function [] = plotAntennaArray(app)
            positions = getElementPosition(app.array);
            antennaLine = [app.lambda/4 0 0; 0 0 0; 0 app.lambda/4 0; 0 0 0; -app.lambda/4 0 0; 0 0 0; 0 -app.lambda/4 0; 0 0 0; 0 0 app.lambda/4];
            
            hold(app.UIAxes_2, 'on')
            for antennaIndex = 1:app.numAntennas
                plot3(app.UIAxes_2, ...
                      antennaLine(:,1) + positions(1,antennaIndex), ...
                      antennaLine(:,2) + positions(2,antennaIndex), ...
                      antennaLine(:,3) + positions(3,antennaIndex), ...
                      'LineWidth', 3 ,'Color','b')
            end
            title(app.UIAxes_2, 'Physical Array Layout')
            zlabel(app.UIAxes_2, 'Z (m)')
            xlabel(app.UIAxes_2, 'X (m)')
            ylabel(app.UIAxes_2, 'Y (m)')
            grid(app.UIAxes_2, 'on')
            hold(app.UIAxes_2, 'off')
            xlim(app.UIAxes_2, [(min(min(positions)) + min(min(antennaLine))) (max(max(positions)) + max(max(antennaLine)))])
            ylim(app.UIAxes_2, [(min(min(positions)) + min(min(antennaLine))) (max(max(positions)) + max(max(antennaLine)))])
            zlim(app.UIAxes_2, [0 (max(max(positions)) + max(max(antennaLine)))/2])
            pbaspect(app.UIAxes_2, [2 2 1])
            view(app.UIAxes_2, [45 45]);
            axis(app.UIAxes_2, 'vis3d')
        end
        
        % Plots the 3D Radiation Pattern (bottom left)
        function [] = plotRadiationPattern(app)
            [pat_phitheta,phi_pat,theta_pat] = azel2phithetapat(app.pattern_values,app.az_values,app.el_values,'RotateZ2X',false);
            [PHI, THT] = meshgrid(phi_pat*pi/180, theta_pat*pi/180);
            pat_phitheta(pat_phitheta == inf) = max(max(pat_phitheta(~isinf(pat_phitheta))));
            pat_phitheta(pat_phitheta == -inf) = min(min(pat_phitheta(~isinf(pat_phitheta))));
            
            [~, X, Y, Z] = plot_antenna_patternv2(app, pat_phitheta, PHI,THT, 50); % 50 is the the lowest drop from max gain the graph will show
            hold(app.UIAxes_3, 'on');
            app.spherical_plot_X = X;
            app.spherical_plot_Y = Y;
            app.spherical_plot_Z = Z;
            app.UIAxes_3.DataAspectRatio =[3,3,3];
            title(app.UIAxes_3, '3D Radiation Pattern');
            axis(app.UIAxes_3, 'off');
            set(app.UIAxes_3,'Xticklabel',[]);
            set(app.UIAxes_3,'Yticklabel',[]);
            set(app.UIAxes_3,'Zticklabel',[]);
            
            grid(app.UIAxes_3, 'off');

            width = 2;% line width
            axis_scale = 1.3;% scale the axes so they stick out of the plot
            fontSize = 12;
            
            max_x = max(X,[],'all');
            max_y = max(Y,[],'all');
            max_z = max(Z,[],'all');
            
            % Plot x,y and z axis labels
            quiver3(app.UIAxes_3,0,0,0,axis_scale*max_x,0,0,'black','LineWidth',width,'ShowArrowHead','off');
            quiver3(app.UIAxes_3,0,0,0,0,axis_scale*max_y,0,'black','LineWidth',width,'ShowArrowHead','off');
            quiver3(app.UIAxes_3,0,0,0,0,0,axis_scale*max_z,'black','LineWidth',width,'ShowArrowHead','off');
            text_distance_Scaler = 1.1;
            text(app.UIAxes_3,text_distance_Scaler*axis_scale*max(X,[],'all'),0,0,'X', 'FontWeight', 'bold', 'FontSize', fontSize);
            text(app.UIAxes_3,0,text_distance_Scaler*axis_scale*max(Y,[],'all'),0,'Y', 'FontWeight', 'bold', 'FontSize', fontSize);
            text(app.UIAxes_3,0,0,text_distance_Scaler*axis_scale*max(Z,[],'all'),'Z', 'FontWeight', 'bold', 'FontSize', fontSize);
            
            
            % plot nulls and peaks
            % EL = 90 - THETA;
            % AZ = PHI;
            
            mag = 1.1*sqrt(max_x^2 + max_y^2 + max_z^2);
            
            %az_target and el_target should be the same length
            for i = 1:length(app.az_target)
                x = mag.*cosd(app.ele_target(i)).*cosd(app.az_target(i));
                y = mag.*cosd(app.ele_target(i)).*sind(app.az_target(i)) * -1;
                z = mag.*sind(app.ele_target(i));
                quiver3(app.UIAxes_3, 0, 0, 0, x, y, z, 'g','LineWidth',width);
            end
            
            if(~isempty(app.altered_nulls))
                %alterred_nulls should be a positive even number
                az = app.altered_nulls(1,:);
                el = app.altered_nulls(2,:);
                for i = 1:length(az)
                    x = mag.*cosd(el(i)).*cosd(az(i));
                    y = mag.*cosd(el(i)).*sind(az(i)) * -1;
                    z = mag.*sind(el(i)) * -1;
                    quiver3(app.UIAxes_3, 0, 0, 0, x, y, z, 'r','LineWidth',width);
                end
            end     
        end
        
        % 3D Radiation Pattern Helper Function
        function [handle,X,Y,Z] = plot_antenna_patternv2(app,gain,PHI,THETA,displacement)
            % 'displacement' determines the smallest gain shown in the plot, anything
            % smaller than this value will be forcely evaluated to this value         
            color_scale=10;
            color_max=color_scale*(ceil(max(max(gain)))+displacement);
            N_ticks=5;
                         
            % coordinate transform
            gain=color_scale*(gain+displacement);
            gain(gain<0)=0;
            X=gain.*sin(THETA).*cos(PHI);
            Y=gain.*sin(THETA).*sin(PHI) * -1;
            Z=gain.*cos(THETA);
            % plot
            handle = surf(app.UIAxes_3, X, Y, Z, gain, 'EdgeColor','none','FaceColor','interp');
            % set up a colorbar
            ticks=linspace(0,color_max,N_ticks);
            ticklabels=textscan(num2str(round(ticks/color_scale-displacement)),'%s');
            ticklabels=ticklabels{1,1}';
            ticklabels{1,N_ticks}=strcat(ticklabels{1,N_ticks}); % ,'(dBi)'
            ticklabels{N_ticks} = ' 0';
            cbr = colorbar(app.UIAxes_3,'Ticks',ticks, 'TickLabels', ticklabels);
            cbr.Limits = [0 color_max];
        end
        
       % Plots an Azimuth Slice (black line showing constant azimuth on
       % the 3D Radiation Plot
        function [] = plotAzimuthSlice(app)
            %graph azimuth slice
            azvalues = app.derived_az_values;
            az_angle = app.AzimuthAngleSlider.Value * -1;
            azindexes = azvalues == az_angle;
            app.az_slice_plot.XData = app.spherical_plot_X(azindexes);
            app.az_slice_plot.YData = app.spherical_plot_Y(azindexes);
            app.az_slice_plot.ZData = app.spherical_plot_Z(azindexes);
          
            drawnow;
        end
        
       % Plots an Elevation Slice (black line showing constant elevation on
       % the 3D Radiation Plot
        function [] = plotElevationSlice(app)
            %graph elevation slice
            elvalues = app.derived_el_values;
            bounds = abs(app.el_values(2)-app.el_values(1))/10;

            el_angle = app.ElevationAngleSlider.Value;
            elindexes = (elvalues >= el_angle - bounds) & (elvalues <= el_angle + bounds);
            app.el_slice_plot.XData = app.spherical_plot_X(elindexes);
            app.el_slice_plot.YData = app.spherical_plot_Y(elindexes);
            app.el_slice_plot.ZData = app.spherical_plot_Z(elindexes);
            
            drawnow;
        end
        
        % Elevation Slice Helper Function
        function [] = updateScatterPlots(app)
            el_angle = app.el_values(i); 
            
            bounds = abs(app.el_values(2)-app.el_values(1))/10;

            el_angle = app.ElevationAngleSlider.Value;
            elindexes = (elvalues >= el_angle - bounds) & (elvalues <= el_angle + bounds);
            
            app.scatter_plots_el(i) = plot3(app.UIAxes_3, ...
                                            app.spherical_plot_X(elindexes), ...
                                            app.spherical_plot_Y(elindexes), ...
                                            app.spherical_plot_Z(elindexes), ...
                                            'black','visible', 'off');
        end
        
        % Enables the Loading gif
        function [] = enableLoadingIcon(app)
            % initialize the loading fig
            app.loadingGIF.Visible = true;
            pause(0.0001); % for some reason, need to delay for MATLAB to draw in the gif
        end
        
        % Enables the Loading gif     
        function [] = disableLoadingIcon(app)
            app.loadingGIF.Visible = false; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.fc = 22e6;        % frequency of signal
            app.lambda = physconst('LightSpeed')/app.fc;     % wavelength of signal
            app.numAntennas = 8;    % number of antennas
            app.antenna = monopoleRadial('Height',app.lambda/4, ...
                                         'RadialLength',app.lambda/4, ...
                                         'Width',.1, ...
                                         'RadialWidth',.1, ...
                                         'NumRadials',4, ...
                                         'Tilt', [40], ...
                                         'TiltAxis', [0 0 1]);
            app.array = phased.ULA('NumElements',app.numAntennas, ...
                                   'Element', app.antenna, ...
                                   'ElementSpacing', app.lambda/2);
            app.az_null = 0;     % null azimuth (degrees)
            app.az_target = 90;   % target azimuth (degrees)
            app.ele_null = 0;    % null elevation (degrees)
            app.ele_target = 0;  % target elevation (degrees)
            app.UITableTargets.Data = [90 0]; % default table values (degrees)
            app.UITableNulls.Data = [0 0]; 
            
            % Hide loading gif
            app.loadingGIF.Visible = false;

            % Map Initialization
            axis(app.UIAxes_Map,'equal');
            axis(app.UIAxes_Map,'off');
            app.map_fig = figure('visible','off');
            app.map_ax = axesm('MapProjection','mercator','MapLatLimit',[-75 80], ...
                                   'MapLonLimit', [-180 180], 'Grid','off','Frame', 'on');
            setm(app.map_ax, 'meridianlabel', 'off', 'parallellabel', 'off')
            land = shaperead('landareas', 'UseGeoCoords', true);
            geoshow(app.map_ax, land, 'FaceColor', [0.5 0.7 0.5])
            tightmap;
            copyobj(app.map_ax.Children, app.UIAxes_Map);  % Copy all of the axis' children to your app axis
            title(app.UIAxes_Map, 'Power Trajectory Map');
            
            % Start up parallel pool of workers
            p = gcp('nocreate');
            if isempty(p)
                % no pool exists so create one
                parpool('threads');
            end     
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Display the Loading Icon
            enableLoadingIcon(app);

            % Parse User Input
            parseUserInput(app);
                      
            % Update the Map
            updateMap(app);

            % Clear out previous plots
            clearOldPlots(app);
            
            % Translate input into bounds the steering vector can handle
            correctTargetParameters(app);
            correctNullParameters(app);
            
            % Check input nulls and target to make sure they don't clobber
            % each other
            keepGoing = nullWarning(app);
            
            % Check to see if app should keep running
            if(keepGoing)
                % Change the input azimuths;
                correctInputAzimuths(app)
                
                % Beamforming Calculations
                if app.isULA
                    [app.pattern_values, app.az_values, app.el_values, app.altered_nulls] = ulaBeamforming(app);
                else
                    [app.pattern_values, app.az_values, app.el_values, app.altered_nulls] = ucaBeamforming(app);
                end
                
                % Redefine the azimuth coming out of pattern
                correctAzimuth(app);
                
                % Handle All Plotting
                handlePlotting(app);
            end
             
            % Turn off the Loading Icon
            disableLoadingIcon(app);
            
            % Enable the export button after a result has been calculated
            app.ExportButton.Enable = 'on';
        end

        % Callback function
        function SliderValueChanging(app, event)
            
        end

        % Value changed function: ArrayShapeDropDown
        function ArrayShapeDropDownValueChanged(app, event)
            value = app.ArrayShapeDropDown.Value;
            display(value);
        end

        % Button pushed function: ButtonNullRemove
        function ButtonNullRemovePushed(app, event)
            if isempty(app.UITableNullsSelectedCells)
                app.UITableNulls.Data(end,:) = []; % Delete Row
            else
                app.UITableNulls.Data(app.UITableNullsSelectedCells(:,1),:) = []; % delete rows
            end
        end

        % Button pushed function: ButtonNullAdd
        function ButtonNullAddPushed(app, event)
            app.UITableNulls.Data(end+1,:) = 0; % Add Row of zeros
        end

        % Callback function
        function ButtonTargetRemovePushed(app, event)
            if isempty(app.UITableTargetsSelectedCells)
                app.UITableTargets.Data(end,:) = []; % Delete Row
            else
                app.UITableTargets.Data(app.UITableTargetsSelectedCells(:,1),:) = []; % delete rows
            end
        end

        % Callback function
        function ButtonTargetAddPushed(app, event)
            app.UITableTargets.Data(end+1,:) = 0; % Add Row of zeros
        end

        % Cell selection callback: UITableTargets
        function UITableTargetsCellSelection(app, event)
            app.UITableTargetsSelectedCells = event.Indices;
        end

        % Cell selection callback: UITableNulls
        function UITableNullsCellSelection(app, event)
            app.UITableNullsSelectedCells = event.Indices;         
        end

        % Value changed function: ElevationAngleEditField
        function ElevationAngleEditFieldValueChanged(app, event)
            if ~isempty(app.el_slice_plot) % don't do anything if the plots are empty
                value = app.ElevationAngleEditField.Value;
                app.slice_el_angle = value; %s tore this so the line can be plotted on the 3d plot
                
                if value ~= app.ElevationAngleSlider.Value % prevent recursive loop
                    
                    % Find closest datapoint to user input value
                    diff = abs(app.el_values - value);
                    index = find(diff == min(diff));
                    index = index(1); % in the case that there is 2 values of equal separation
                    app.ElevationAngleEditField.Value = app.el_values(index);
                    
                    % Azimuth Plot
                    plotAzimuth(app, app.ElevationAngleEditField.Value)
                    
                    % Slice
                    plotElevationSlice(app);
                    app.ElevationAngleSlider.Value = app.el_values(index); % change the slider value to match user input value
                    drawnow;

                end
        
            end

        end

        % Value changed function: ElevationAngleSlider
        function ElevationAngleSliderValueChanged(app, event)
            if ~isempty(app.el_slice_plot)
            value = app.ElevationAngleSlider.Value;
            
                if value ~= app.ElevationAngleEditField.Value
                diff = abs(app.el_values - value);
                index = find(diff == min(diff));
                index = index(1); % in the case that there is 2 values of equal separation
                app.ElevationAngleSlider.Value = app.el_values(index);

                
                % Azimuth Plot
                plotAzimuth(app, app.ElevationAngleSlider.Value)
                
                % Slice            
                plotElevationSlice(app);
                app.ElevationAngleEditField.Value = app.el_values(index);
                drawnow;
                end
    

            end

        end

        % Value changed function: AzimuthAngleEditField
        function AzimuthAngleEditFieldValueChanged(app, event)
            if ~isempty(app.az_slice_plot)
                value = app.AzimuthAngleEditField.Value;
                app.slice_az_angle = value;
                
                if value ~= app.AzimuthAngleSlider.Value
                    diff = abs(app.az_values - value);
                    index = find(diff == min(diff));
                    index = index(1); % in the case that there is 2 values of equal separation
                    app.AzimuthAngleEditField.Value = app.az_values(index);
                    
                    % Elevation Plot
                    plotElevation(app, app.AzimuthAngleEditField.Value)
                    
                    % Slice

                    plotAzimuthSlice(app);
                    
                    app.AzimuthAngleSlider.Value = app.az_values(index);
                    drawnow;
                end
            end
            
            %display(value);
        end

        % Value changed function: AzimuthAngleSlider
        function AzimuthAngleSliderValueChanged(app, event)
            if ~isempty(app.az_slice_plot)
            value = app.AzimuthAngleSlider.Value;
            
                if value ~= app.AzimuthAngleEditField.Value
                diff = abs(app.az_values - value);
                index = find(diff == min(diff));
                index = index(1); % in the case that there is 2 values of equal separation
                app.AzimuthAngleSlider.Value = app.az_values(index);
                
                % Elevation Plot
                plotElevation(app, app.AzimuthAngleSlider.Value)
                
                % Slice
                plotAzimuthSlice(app);
                
                app.AzimuthAngleEditField.Value = app.az_values(index);
                drawnow;
                end
            end

        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % get values to be printed to csv
            antennaIndex = (1:app.numAntennas)'; 
            elementpos = -(getElementPosition(app.array))';
            antennaX = elementpos(:,1);
            antennaY = elementpos(:,2);
            mag = app.conformal_array.AmplitudeTaper(1:app.numAntennas);
            phase = app.conformal_array.PhaseShift(1:app.numAntennas);
            heights = zeros(app.numAntennas, 1) + app.lambda/4;
            % put values into a table with headers
            T1 = table(antennaIndex, antennaX, antennaY, mag, phase, heights, 'VariableNames',{'Antenna Index', 'Antenna X (meters)', 'Antenna Y (meters)', 'Antenna Magnitude (scalar)', 'Antenna Phase (deg)', 'Antenna Height (meters)'});
            % create system save dialog and save
            [file,path] = uiputfile('*.csv');
            filename = fullfile(path,file);
            if ~(strcmp(filename,fullfile(0,0))) % if the user doesn't complete file finder, don't save
               writetable(T1, filename)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.loadingGIF = uiimage(app.UIFigure);
            app.loadingGIF.ImageSource = 'Loader.gif';
            app.ElevationAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.ElevationAngleEditField.ValueChangedFcn = createCallbackFcn(app, @ElevationAngleEditFieldValueChanged, true);
            app.ElevationAngleSliderLabel = uilabel(app.UIFigure);
            app.ElevationAngleSliderLabel.Text = 'Elevation Angle';
            app.ElevationAngleSlider = uislider(app.UIFigure);
            app.ElevationAngleSlider.Limits = [0 90];
            app.ElevationAngleSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90];
            app.ElevationAngleSlider.ValueChangedFcn = createCallbackFcn(app, @ElevationAngleSliderValueChanged, true);
            app.AzimuthAngleLabel = uilabel(app.UIFigure);
            app.AzimuthAngleLabel.Text = 'Azimuth Angle';
            app.AzimuthAngleSlider = uislider(app.UIFigure);
            app.AzimuthAngleSlider.Limits = [-180 180];
            app.AzimuthAngleSlider.ValueChangedFcn = createCallbackFcn(app, @AzimuthAngleSliderValueChanged, true);
            app.AzimuthAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.AzimuthAngleEditField.ValueChangedFcn = createCallbackFcn(app, @AzimuthAngleEditFieldValueChanged, true);
            app.NotePositiveXisNorthLabel = uilabel(app.UIFigure);
            app.NotePositiveXisNorthLabel.Text = 'Note: Positive X is North';
            app.UserControlPanel = uipanel(app.UIFigure);
            app.UserControlPanel.Title = 'User Control Panel';
            app.RunButton = uibutton(app.UserControlPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.MHzLabel = uilabel(app.UserControlPanel);
            app.MHzLabel.Text = 'MHz';
            app.UITableTargets = uitable(app.UserControlPanel);
            app.UITableTargets.ColumnName = {'Azimuth'; 'Elevation'};
            app.UITableTargets.RowName = {};
            app.UITableTargets.ColumnEditable = true;
            app.UITableTargets.CellSelectionCallback = createCallbackFcn(app, @UITableTargetsCellSelection, true);
            app.TargetDirectionLabel = uilabel(app.UserControlPanel);
            app.TargetDirectionLabel.Text = 'Target Direction';
            app.UITableNulls = uitable(app.UserControlPanel);
            app.UITableNulls.ColumnName = {'Azimuth'; 'Elevation'};
            app.UITableNulls.RowName = {};
            app.UITableNulls.ColumnEditable = true;
            app.UITableNulls.CellSelectionCallback = createCallbackFcn(app, @UITableNullsCellSelection, true);
            app.NullDirectionsLabel = uilabel(app.UserControlPanel);
            app.NullDirectionsLabel.Text = 'Null Directions';
            app.ButtonNullAdd = uibutton(app.UserControlPanel, 'push');
            app.ButtonNullAdd.ButtonPushedFcn = createCallbackFcn(app, @ButtonNullAddPushed, true);
            app.ButtonNullAdd.Text = '+';
            app.ButtonNullRemove = uibutton(app.UserControlPanel, 'push');
            app.ButtonNullRemove.ButtonPushedFcn = createCallbackFcn(app, @ButtonNullRemovePushed, true);
            app.ButtonNullRemove.Text = '-';
            app.ExportButton = uibutton(app.UserControlPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export';
            app.NumberofAntennasEditFieldLabel = uilabel(app.UserControlPanel);
            app.NumberofAntennasEditFieldLabel.Text = 'Number of Antennas';
            app.NumberofAntennasEditField = uieditfield(app.UserControlPanel, 'numeric');
            app.NumberofAntennasEditField.Limits = [2 8];
            app.NumberofAntennasEditField.RoundFractionalValues = 'on';
            app.NumberofAntennasEditField.Value = 8;
            app.ArrayShapeDropDownLabel = uilabel(app.UserControlPanel);
            app.ArrayShapeDropDownLabel.Text = 'Array Shape';
            app.ArrayShapeDropDown = uidropdown(app.UserControlPanel);
            app.ArrayShapeDropDown.Items = {'ULA', 'UCA'};
            app.ArrayShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @ArrayShapeDropDownValueChanged, true);
            app.ArrayShapeDropDown.Value = 'ULA';
            app.FrequencyEditFieldLabel = uilabel(app.UserControlPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UserControlPanel, 'numeric');
            app.FrequencyEditField.Limits = [5 22];
            app.FrequencyEditField.Value = 22;
            app.ArrayLocationDDLabel = uilabel(app.UserControlPanel);
            app.ArrayLocationDDLabel.Text = 'Array Location (DD) ';
            app.ArrayLocationEditFieldLat = uieditfield(app.UserControlPanel, 'numeric');
            app.ArrayLocationEditFieldLat.Limits = [-90 90];
            app.ArrayLocationEditFieldLat.ValueDisplayFormat = '%.5f';
            app.ArrayLocationEditFieldLat.Value = -1.5072;
            app.ArrayLocationEditFieldLon = uieditfield(app.UserControlPanel, 'numeric');
            app.ArrayLocationEditFieldLon.Limits = [-180 180];
            app.ArrayLocationEditFieldLon.ValueDisplayFormat = '%.5f';
            app.ArrayLocationEditFieldLon.Value = 143.6436;
            app.Label = uilabel(app.UserControlPanel);
            app.Label.Text = ',';
            app.NotePositiveXisNorthLabel_2 = uilabel(app.UIFigure);
            app.NotePositiveXisNorthLabel_2.Text = 'Note: Positive X is North';
            app.HFDistributedBeamformingModelLabel = uilabel(app.UIFigure);
            app.HFDistributedBeamformingModelLabel.Text = 'HF Distributed Beamforming Model';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Elevation Cross-Section')
            xlabel(app.UIAxes, 'Azimuth Angle (degrees)')
            ylabel(app.UIAxes, 'Normalized Power (dB)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Physical Array Layout')
            xlabel(app.UIAxes_2, 'X (m)')
            ylabel(app.UIAxes_2, 'Y (m)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '3D Radiation Pattern')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Azimuth Cross-Section')
            xlabel(app.UIAxes_4, 'Elevation Angle (degrees)')
            ylabel(app.UIAxes_4, 'Normalized Power (dB)')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_Map = uiaxes(app.UIFigure);
            title(app.UIAxes_Map, 'Power Trajectory Map')
            zlabel(app.UIAxes_Map, 'Z')
            app.UIAxes_Map.XTick = [];
            app.UIAxes_Map.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = distributedHFBeamforming

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end